classdef EMSO_solarPV_optimizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RooftopPVOptimizerforEMSOUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        BUILDINGPOLYGONSettingsLabel    matlab.ui.control.Label
        PixelLengthEditFieldLabel       matlab.ui.control.Label
        PixelLengthEditField            matlab.ui.control.NumericEditField
        PANELWALKWAYSettingsLabel       matlab.ui.control.Label
        PanelUnitDropDown               matlab.ui.control.DropDown
        PanellengthEditFieldLabel       matlab.ui.control.Label
        PanellengthEditField            matlab.ui.control.EditField
        PanelwidthEditFieldLabel        matlab.ui.control.Label
        PanelwidthEditField             matlab.ui.control.EditField
        PaneltiltEditFieldLabel         matlab.ui.control.Label
        PaneltiltEditField              matlab.ui.control.NumericEditField
        PanelTiltUnitBox                matlab.ui.control.Button
        PanelOrientationDropDown        matlab.ui.control.DropDown
        WalkwayminwidthEditFieldLabel   matlab.ui.control.Label
        WalkwayminwidthEditField        matlab.ui.control.NumericEditField
        WalkwayUnitDropDown             matlab.ui.control.DropDown
        ForcepolygonapproximationLabel  matlab.ui.control.Label
        SelectBuildingsListBoxLabel     matlab.ui.control.Label
        SelectBuildingsListBox          matlab.ui.control.ListBox
        LatitudeEditFieldLabel          matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        checkbox_polyapprox             matlab.ui.control.CheckBox
        ForceshadowstudyLabel           matlab.ui.control.Label
        checkbox_shadows                matlab.ui.control.CheckBox
        ShadowstudyresolutionscaleEditFieldLabel  matlab.ui.control.Label
        ShadowstudyresolutionscaleEditField  matlab.ui.control.NumericEditField
        path_meshButton                 matlab.ui.control.Button
        path_imagesButton               matlab.ui.control.Button
        path_outputButton               matlab.ui.control.Button
        path_outputtransferButton       matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        PanelorientationLabel           matlab.ui.control.Label
        InputmeshpathEditFieldLabel     matlab.ui.control.Label
        InputmeshpathEditField          matlab.ui.control.EditField
        InputsatellitephotopathEditFieldLabel  matlab.ui.control.Label
        InputsatellitephotopathEditField  matlab.ui.control.EditField
        OutputsavefilepathEditFieldLabel  matlab.ui.control.Label
        OutputsavefilepathEditField     matlab.ui.control.EditField
        OutputpathEditFieldLabel        matlab.ui.control.Label
        OutputpathEditField             matlab.ui.control.EditField
    end

    
    properties (Access = private)
        buildings       % List of selected buildings
        clrsky          % Nima's clear sky index table
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Hide buttons
            
            % Establish buildings
            buildings_list = ["ADMIN"; "BioSci";  "CAM"; "CCIS"; "DICE"; ...
                              "ETLC";     "GSB"; "NINT"; "NREF";  "SUB"; ...
                              "Pembina"; "VVC"];
            app.SelectBuildingsListBox.Items = buildings_list;
            
            % Establish input/output paths
            app.InputmeshpathEditField.Value = "meshes/";
            app.InputsatellitephotopathEditField.Value = "images/";
            app.OutputsavefilepathEditField.Value = "output/";
            app.OutputpathEditField.Value = "output/transfer/";
            
            % Initialize Nima's code
            app.clrsky=readtable('Clear-sky-index.xlsx');
            app.clrsky=table2array(app.clrsky);
            app.clrsky = [0;app.clrsky];        % (!) Joseph edit. I assume MATLAB skips the first line of the '.xlsx' file by treating it as a header.
        end

        % Button pushed function: path_meshButton
        function path_meshButtonPushed(app, event)
            filepath = uigetdir();
            if isequal(filepath,0)
%                disp('User selected Cancel');
            else
%                disp(['User selected ', filepath]);
                app.InputmeshpathEditField.Value = [filepath,'\'];
            end
        end

        % Button pushed function: path_imagesButton
        function path_imagesButtonPushed(app, event)
            filepath = uigetdir();
            if isequal(filepath,0)
%                disp('User selected Cancel');
            else
%                disp(['User selected ', filepath]);
                app.InputsatellitephotopathEditField.Value = [filepath,'\'];
            end
        end

        % Button pushed function: path_outputButton
        function path_outputButtonPushed(app, event)
            filepath = uigetdir();
            if isequal(filepath,0)
%                disp('User selected Cancel');
            else
%                disp(['User selected ', filepath]);
                app.OutputsavefilepathEditField.Value = [filepath,'\'];
            end
        end

        % Button pushed function: path_outputtransferButton
        function path_outputtransferButtonPushed(app, event)
            filepath = uigetdir();
            if isequal(filepath,0)
%                disp('User selected Cancel');
            else
%                disp(['User selected ', filepath]);
                app.OutputpathEditField.Value = [filepath,'\'];
            end
        end

        % Value changed function: SelectBuildingsListBox
        function SelectBuildingsListBoxValueChanged(app, event)
            value = app.SelectBuildingsListBox.Value;
            if isempty(value)
                app.STARTButton.Enable = false;
            else
                app.STARTButton.Enable = true;
            end
            app.buildings = value;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            u = symunit;
            % Grey out EVERYTHING!
            app.LatitudeEditField.Enable = false;
            app.PixelLengthEditField.Enable = false;
            app.ShadowstudyresolutionscaleEditField.Enable = false;
            app.checkbox_shadows.Enable = false;
            app.checkbox_polyapprox.Enable = false;
            app.PanelOrientationDropDown.Enable = false;
            app.PaneltiltEditField.Enable = false;
            app.PanelTiltUnitBox.Enable = false;
            app.PanellengthEditField.Enable = false;
            app.PanelwidthEditField.Enable = false;
            app.PanelUnitDropDown.Enable = false;
            app.WalkwayminwidthEditField.Enable = false;
            app.WalkwayUnitDropDown.Enable = false;
            app.InputmeshpathEditField.Enable = false;
            app.InputsatellitephotopathEditField.Enable = false;
            app.OutputsavefilepathEditField.Enable = false;
            app.OutputpathEditField.Enable = false;
            app.path_meshButton.Enable = false;
            app.path_imagesButton.Enable = false;
            app.path_outputButton.Enable = false;
            app.path_outputtransferButton.Enable = false;
            app.SelectBuildingsListBox.Enable = false;
            
            % Disable Start button; alter text
            app.STARTButton.Text = "RUNNING";
            app.STARTButton.Enable = false;
            
            pause(1);           % Prevents Buildings from generating before UI is greyed out    
            
            
            % Set up units
            switch app.PanelUnitDropDown.Value
                case "inches"
                    panel_units = u.in;
                case "feet"
                    panel_units = u.ft;
                case "centimetres"
                    panel_units = u.cm;
                case "metres"
                    panel_units = u.m;
                otherwise
                    panel_units = u.in;
                    error(app.PanelUnitDropDown.Value+" not functional!");                    
            end
            switch app.WalkwayUnitDropDown.Value
                case "metres"
                    walkway_units = u.m;
                case "feet"
                    walkway_units = u.ft;
                otherwise
                    error(app.WalkwayUnitDropDown.Value+" not functional!");
            end
            switch app.PanelTiltUnitBox.Text
                case "degrees"
                    tilt_units = u.deg;
                otherwise
                    error(app.PanelTiltUnitBox.Text+" has incorrect units.")
            end
            
            % Initialize Building parameters
            pixlength = app.PixelLengthEditField.Value;
                panel_y = app.PanellengthEditField.Value*panel_units;
                panel_x = app.PanelwidthEditField.Value*panel_units;
            panelSpecs = [panel_y,panel_x];
            panelTilt = app.PaneltiltEditField.Value * tilt_units;
            panelOrientation = lower(app.PanelOrientationDropDown.Value);
            walkway_length = app.WalkwayminwidthEditField.Value * walkway_units;
            force_run_poly = app.checkbox_polyapprox.Value;
            lat = app.LatitudeEditField.Value;
            force_run_shadow = app.checkbox_shadows.Value;
            shadow_scale = app.ShadowstudyresolutionscaleEditField.Value;
            % Path names
            input_path_meshes = app.InputmeshpathEditField.Value;
            input_path_photos = app.InputsatellitephotopathEditField.Value;
            output_path_save  = app.OutputsavefilepathEditField.Value;
            output_path = app.OutputpathEditField.Value;
            if input_path_meshes(end) ~= '/'
                input_path_meshes = [input_path_meshes,'/'];
            end
            if input_path_photos(end) ~= '/'
                input_path_photos = [input_path_photos,'/'];
            end
            if output_path_save(end) ~= '/'
                output_path_save = [output_path_save,'/'];
            end
            if output_path(end) ~= '/'
                output_path = [output_path,'/'];
            end
            % Building selection
            value = app.SelectBuildingsListBox.Value;
            app.buildings = value;
            
            % Generate all information for each selected building!
            for i = 1:length(app.buildings)
                name = convertCharsToStrings(app.buildings{i});
                
                % Run Joseph's code
                disp(name+": Generating `Building` object.");
                tic
                b = Building(name,'pixlength',pixlength,'panelSpecs',panelSpecs,'panelTilt',panelTilt,'panelOrientation',panelOrientation, ...
                    'walkway_length',walkway_length,'force_run_poly',force_run_poly,'lat',lat, ...
                    'force_run_shadow',force_run_shadow,'shadow_scale',shadow_scale, ...
                    'input_path_meshes',input_path_meshes,'input_path_photos',input_path_photos, ...
                    'output_path_save',output_path_save,'output_path',output_path);
                toc
                
                % Save output
                eval("table = b.info");
                eval("pix2m = b.pix2m;");
                eval("shadows = b.shadows;");
                eval("heightmap = b.Z;");
                save(output_path+name+"_info.mat","table","pix2m","shadows","heightmap");
                
                % Run Nima's code
                disp(name+": Optimizing rooftop generation...");
                generate_tables(name,app.clrsky,output_path);
                disp(name+": ... finished!");
            end            
            
            
            % Reenable EVERYTHING!
%             app.LatitudeEditField.Enable = true;
            app.PixelLengthEditField.Enable = true;
            app.ShadowstudyresolutionscaleEditField.Enable = true;
            app.checkbox_shadows.Enable = true;
            app.checkbox_polyapprox.Enable = true;
            app.PanelOrientationDropDown.Enable = true;
            app.PaneltiltEditField.Enable = true;
%             app.PanelTiltUnitBox.Enable = true;
            app.PanellengthEditField.Enable = true;
            app.PanelwidthEditField.Enable = true;
            app.PanelUnitDropDown.Enable = true;
            app.WalkwayminwidthEditField.Enable = true;
            app.WalkwayUnitDropDown.Enable = true;
            app.InputmeshpathEditField.Enable = true;
            app.InputsatellitephotopathEditField.Enable = true;
            app.OutputsavefilepathEditField.Enable = true;
            app.OutputpathEditField.Enable = true;
            app.path_meshButton.Enable = true;
            app.path_imagesButton.Enable = true;
            app.path_outputButton.Enable = true;
            app.path_outputtransferButton.Enable = true;
            app.SelectBuildingsListBox.Enable = true;
            
            % Enable Start button; alter text
            app.STARTButton.Text = "START";
            app.STARTButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RooftopPVOptimizerforEMSOUIFigure = uifigure('Visible', 'off');
            app.RooftopPVOptimizerforEMSOUIFigure.Name = 'Rooftop PV Optimizer for EMSO';
            app.GridLayout = uigridlayout(app.RooftopPVOptimizerforEMSOUIFigure);
            app.BUILDINGPOLYGONSettingsLabel = uilabel(app.GridLayout);
            app.BUILDINGPOLYGONSettingsLabel.Text = 'BUILDING+POLYGON Settings';
            app.PixelLengthEditFieldLabel = uilabel(app.GridLayout);
            app.PixelLengthEditFieldLabel.Tooltip = {'Number of pixels comprising length of height map.'};
            app.PixelLengthEditFieldLabel.Text = 'Pixel Length';
            app.PixelLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.PixelLengthEditField.ValueDisplayFormat = '%.0f';
            app.PixelLengthEditField.Tooltip = {''};
            app.PixelLengthEditField.Value = 1600;
            app.PANELWALKWAYSettingsLabel = uilabel(app.GridLayout);
            app.PANELWALKWAYSettingsLabel.Text = 'PANEL+WALKWAY Settings';
            app.PanelUnitDropDown = uidropdown(app.GridLayout);
            app.PanelUnitDropDown.Items = {'inches', 'feet', 'centimetres', 'metres'};
            app.PanelUnitDropDown.Value = 'inches';
            app.PanellengthEditFieldLabel = uilabel(app.GridLayout);
            app.PanellengthEditFieldLabel.Text = 'Panel length';
            app.PanellengthEditField = uieditfield(app.GridLayout, 'text');
            app.PanellengthEditField.Value = '65';
            app.PanelwidthEditFieldLabel = uilabel(app.GridLayout);
            app.PanelwidthEditFieldLabel.Text = 'Panel width';
            app.PanelwidthEditField = uieditfield(app.GridLayout, 'text');
            app.PanelwidthEditField.Value = '39';
            app.PaneltiltEditFieldLabel = uilabel(app.GridLayout);
            app.PaneltiltEditFieldLabel.Text = 'Panel tilt';
            app.PaneltiltEditField = uieditfield(app.GridLayout, 'numeric');
            app.PanelTiltUnitBox = uibutton(app.GridLayout, 'push');
            app.PanelTiltUnitBox.Enable = 'off';
            app.PanelTiltUnitBox.Text = 'degrees';
            app.PanelOrientationDropDown = uidropdown(app.GridLayout);
            app.PanelOrientationDropDown.Items = {'Landscape', 'Portrait'};
            app.PanelOrientationDropDown.Value = 'Landscape';
            app.WalkwayminwidthEditFieldLabel = uilabel(app.GridLayout);
            app.WalkwayminwidthEditFieldLabel.Tooltip = {'Legally required minimum distance from panel to rooftop edge.'};
            app.WalkwayminwidthEditFieldLabel.Text = 'Walkway min. width';
            app.WalkwayminwidthEditField = uieditfield(app.GridLayout, 'numeric');
            app.WalkwayminwidthEditField.Tooltip = {'Legally required minimum distance from panel to rooftop edge.'};
            app.WalkwayminwidthEditField.Value = 1.8;
            app.WalkwayUnitDropDown = uidropdown(app.GridLayout);
            app.WalkwayUnitDropDown.Items = {'metres', 'feet'};
            app.WalkwayUnitDropDown.Value = 'metres';
            app.ForcepolygonapproximationLabel = uilabel(app.GridLayout);
            app.ForcepolygonapproximationLabel.Tooltip = {'Forces polygon approx. to run regardless of whether saved files exist.'};
            app.ForcepolygonapproximationLabel.Text = {'Force polygon '; 'approximation'};
            app.SelectBuildingsListBoxLabel = uilabel(app.GridLayout);
            app.SelectBuildingsListBoxLabel.Text = 'Select Building(s)';
            app.SelectBuildingsListBox = uilistbox(app.GridLayout);
            app.SelectBuildingsListBox.Multiselect = 'on';
            app.SelectBuildingsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectBuildingsListBoxValueChanged, true);
            app.SelectBuildingsListBox.Value = {'Item 1'};
            app.LatitudeEditFieldLabel = uilabel(app.GridLayout);
            app.LatitudeEditFieldLabel.Tooltip = {'Latitude at the building''s location.'};
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.GridLayout, 'numeric');
            app.LatitudeEditField.ValueDisplayFormat = '%.4f';
            app.LatitudeEditField.Enable = 'off';
            app.LatitudeEditField.Tooltip = {''};
            app.LatitudeEditField.Value = 53.5461;
            app.checkbox_polyapprox = uicheckbox(app.GridLayout);
            app.checkbox_polyapprox.Text = '';
            app.ForceshadowstudyLabel = uilabel(app.GridLayout);
            app.ForceshadowstudyLabel.Tooltip = {'Forces shadow study to run from scratch regardless of whether saved file exists.'};
            app.ForceshadowstudyLabel.Text = 'Force shadow study';
            app.checkbox_shadows = uicheckbox(app.GridLayout);
            app.checkbox_shadows.Text = '';
            app.ShadowstudyresolutionscaleEditFieldLabel = uilabel(app.GridLayout);
            app.ShadowstudyresolutionscaleEditFieldLabel.Tooltip = {'Resolution scale (from 0 to 1) of heightmap when calculating shadows.'; 'Higher values are slower.'};
            app.ShadowstudyresolutionscaleEditFieldLabel.Text = {'Shadow study'; 'resolution scale'};
            app.ShadowstudyresolutionscaleEditField = uieditfield(app.GridLayout, 'numeric');
            app.ShadowstudyresolutionscaleEditField.Tooltip = {''};
            app.ShadowstudyresolutionscaleEditField.Value = 0.3;
            app.path_meshButton = uibutton(app.GridLayout, 'push');
            app.path_meshButton.ButtonPushedFcn = createCallbackFcn(app, @path_meshButtonPushed, true);
            app.path_meshButton.Text = 'Browse';
            app.path_imagesButton = uibutton(app.GridLayout, 'push');
            app.path_imagesButton.ButtonPushedFcn = createCallbackFcn(app, @path_imagesButtonPushed, true);
            app.path_imagesButton.Text = 'Browse';
            app.path_outputButton = uibutton(app.GridLayout, 'push');
            app.path_outputButton.ButtonPushedFcn = createCallbackFcn(app, @path_outputButtonPushed, true);
            app.path_outputButton.Text = 'Browse';
            app.path_outputtransferButton = uibutton(app.GridLayout, 'push');
            app.path_outputtransferButton.ButtonPushedFcn = createCallbackFcn(app, @path_outputtransferButtonPushed, true);
            app.path_outputtransferButton.Text = 'Browse';
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Tooltip = {'Generates all information for each selected building, one at a time.'};
            app.STARTButton.Text = 'START';
            app.PanelorientationLabel = uilabel(app.GridLayout);
            app.PanelorientationLabel.Text = 'Panel orientation';
            app.InputmeshpathEditFieldLabel = uilabel(app.GridLayout);
            app.InputmeshpathEditFieldLabel.Tooltip = {'Path to input building meshes (.STL).'};
            app.InputmeshpathEditFieldLabel.Text = 'Input mesh path';
            app.InputmeshpathEditField = uieditfield(app.GridLayout, 'text');
            app.InputmeshpathEditField.Tooltip = {'Path to input building meshes (.STL).'};
            app.InputsatellitephotopathEditFieldLabel = uilabel(app.GridLayout);
            app.InputsatellitephotopathEditFieldLabel.Tooltip = {'Path to input satellite photos (.png or .jpg).'};
            app.InputsatellitephotopathEditFieldLabel.Text = 'Input satellite photo path';
            app.InputsatellitephotopathEditField = uieditfield(app.GridLayout, 'text');
            app.InputsatellitephotopathEditField.Tooltip = {'Path to input satellite photos (.png or .jpg).'};
            app.OutputsavefilepathEditFieldLabel = uilabel(app.GridLayout);
            app.OutputsavefilepathEditFieldLabel.Tooltip = {'Output folder containing "save" files between runs.'};
            app.OutputsavefilepathEditFieldLabel.Text = 'Output savefile path';
            app.OutputsavefilepathEditField = uieditfield(app.GridLayout, 'text');
            app.OutputsavefilepathEditField.Tooltip = {'Output folder containing "save" files between runs.'};
            app.OutputpathEditFieldLabel = uilabel(app.GridLayout);
            app.OutputpathEditFieldLabel.Tooltip = {'Output path containing final tables & rooftop information.'};
            app.OutputpathEditFieldLabel.Text = 'Output path';
            app.OutputpathEditField = uieditfield(app.GridLayout, 'text');
            app.OutputpathEditField.Tooltip = {'Output path containing final tables & rooftop information.'};
            % Show the figure after all components are created
            app.RooftopPVOptimizerforEMSOUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMSO_solarPV_optimizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RooftopPVOptimizerforEMSOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RooftopPVOptimizerforEMSOUIFigure)
        end
    end
end