classdef roi_app_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        userinstructionsLabel     matlab.ui.control.Label
        FinalizeButton            matlab.ui.control.Button
        UpdateselectionButton     matlab.ui.control.Button
        CancelselectionButton     matlab.ui.control.Button
        SelectionModeButtonGroup  matlab.ui.container.ButtonGroup
        NewRegionButton           matlab.ui.control.ToggleButton
        AddSelectionButton        matlab.ui.control.ToggleButton
        RemoveSelectionButton     matlab.ui.control.ToggleButton
        DrawNewButton             matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
    end

    
    properties (Access = private)
        hmap        % Actual height map, with a minimum value of zero.
        map         % Colour map, corresponding to hmap.
        mask        % Binary mask, indicating building (1) or background (0).
        cmap_good
        cmap_bad
        cmap_combined
        rect
        outputname  % Filename of output mask (.mat)
        ax1     % First panel
        ax2     % Second panel
    end
    
    methods (Access = private)
        
%         function results = update_mask(app)
        function update_display(app)
            % Update hmap display
            hmap_disp = app.hmap;
            hmap_disp = hmap_disp + ~app.mask*max(app.hmap,[],'all');   % "Mask" from app.mask selection.
            hmap_disp(~isfinite(hmap_disp)) = max(app.hmap,[],'all');   % "Mask" out any NaN regions.
            hmap_disp = hmap_disp/(2*max(app.hmap,[],'all'));           % "Normalize", such that final display uses the combined cmap.
%             app.hmap_disp = hmap_disp;

            % Actually display it
%             imshow(hmap_disp,'colormap',app.cmap_combined,'Parent',app.UIAxes);
            imshow(uint8(app.map),'Parent',app.ax1,'InitialMagnification','fit');   % colourmap
            imshow(hmap_disp,'colormap',app.cmap_combined,'Parent',app.ax2);
            
            % Hide "update"/"cancel" buttons; enable mode select. Add new instructions.
            set(app.UpdateselectionButton,'Enable','off');
            set(app.CancelselectionButton,'Enable','off');
            set(app.NewRegionButton,'Enable','on');
            set(app.AddSelectionButton,'Enable','on');
            set(app.RemoveSelectionButton,'Enable','on');
            set(app.FinalizeButton,'Enable','on');
            set(app.DrawNewButton,'Enable','on');
            instructions = "Edit or finalize current selection.";
            set(app.userinstructionsLabel,'Text',instructions);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, name, hmap, map, outputname)
            % Get map resolution
            width = size(hmap,2);
            height = size(hmap,1);
            ScreenSize = get(groot,'ScreenSize');
            screen_height = ScreenSize(4);
            screen_width  = ScreenSize(3);
            
            % Configure window position + size
            posit = app.UIFigure.Position;
            win_ypos   = posit(1);  % y-distance from top of screen to top of window
            win_xpos   = posit(2);  % x-distance from left of screen to left of window
            win_width  = posit(3);    % width of window (from left)
            win_height = posit(4);    % height of window (from bottom)
%                 % Modifications to window size    % (DISCARD for now)
%                 win_height = round( screen_height*0.7 );
%                 win_width  = round( min(screen_width*10/11, win_height*max([width/height,height/width])) );
%                 win_ypos  = round(screen_height/12);
%                 win_xpos  = round(screen_width/12);
            app.UIFigure.Position = [win_ypos,win_xpos,win_width,win_height];
            
            
            % Define variables
            hmap = hmap - min(hmap,[],'all');   % Lowest value must be zero.
            
            % Colormap selection
            cmap_length = 64;
            cmap_good = brewermap([cmap_length],'RdYlBu');    % Selected
            cmap_bad = gray(cmap_length);                               % Deselected
            cmap_combined = [cmap_good; cmap_bad];          % Displays both together
            
            % Input initial rectangle
                % Initialize figure
%                 app.UIAxes.Title.String = name;
                app.UIFigure.AutoResizeChildren = 'on';
                app.Panel.AutoResizeChildren = 'off';
                % Orient subplots based on image shape
                if width>height*1.2
                    app.ax1 = subplot(2,1,1,'Parent',app.Panel);
                    app.ax2 = subplot(2,1,2,'Parent',app.Panel);
                else
                    app.ax1 = subplot(1,2,1,'Parent',app.Panel);
                    app.ax2 = subplot(1,2,2,'Parent',app.Panel);
                end
                
                % Display user instructions
                instructions = "Select rectangular region that approximates the target building."+newline;
                set(app.userinstructionsLabel,'Text',instructions);
                
                % Display map
%                 imshow(hmap/max(hmap,[],'all'),'colormap',cmap_good,'Parent',app.UIAxes);
                imshow(uint8(map),'Parent',app.ax1,'InitialMagnification','fit');   % colourmap
                imshow(hmap/max(hmap,[],'all'),'colormap',cmap_good,'Parent',app.ax2,'InitialMagnification','fit');
            
                % Actual input with ROI
%                 app.rect = drawrectangle(app.UIAxes);
                app.rect = drawrectangle(app.ax2);
                selected = createMask(app.rect);
                
                % Update user instructions; make "Confirm" button
                % available.
                instructions = "Adjust selected region, or press 'Update selection' to confirm current selection."+newline;
                set(app.userinstructionsLabel,'Text',instructions);
                set(app.UpdateselectionButton, 'Enable','on');
                
            % Save variables as properties, to be accessed by callbacks.
            app.mask        = selected;
            app.hmap = hmap;
            app.map = map;
            app.cmap_good = cmap_good;
            app.cmap_bad = cmap_bad;
            app.cmap_combined = cmap_combined;
            if endsWith(outputname,".mat")
                app.outputname = outputname;
            else
                app.outputname = outputname + ".mat";
            end
                
        end

        % Button pushed function: UpdateselectionButton
        function UpdateselectionButtonPushed(app, event)
            % Choose what to do with current rectangle selection
            % based on which SelectionModeButtonGroup is selected.
            selected = createMask(app.rect);
            if app.NewRegionButton.Value == true
                app.mask = selected;
            elseif app.AddSelectionButton.Value == true
                app.mask = logical(app.mask) + logical(selected);
            elseif app.RemoveSelectionButton.Value == true
                app.mask = logical(app.mask) .* ~logical(selected);
            end         
            
            update_display(app);
        end

        % Button pushed function: CancelselectionButton
        function CancelselectionButtonPushed(app, event)
            % Cancel current selection.
            discard = createMask(app.rect);
            update_display(app);
        end

        % Button pushed function: FinalizeButton
        function FinalizeButtonPushed(app, event)
            % Save file and exit
            mask = app.mask;
            mask_id = randi([1,999999999],1);   % Randomly-selected "mask ID". This is so that polygon approximation is performed per mask.
            save(app.outputname, 'mask', 'mask_id');
            disp("Saved output mask to "+app.outputname+".");
            closereq
        end

        % Button pushed function: DrawNewButton
        function DrawNewButtonPushed(app, event)
            % Enter "drawing" mode right away, and re-enable Update/Cancel when a rectangle is drawn!
            % Display user instructions
            instructions = "Select rectangular region.";
            set(app.userinstructionsLabel,'Text',instructions);
            set(app.UpdateselectionButton,'Enable','off');
            set(app.CancelselectionButton,'Enable','off');
            set(app.DrawNewButton,'Enable','off');
            set(app.FinalizeButton,'Enable','off');
        
            % Actual input with ROI
%             app.rect = drawrectangle(app.UIAxes);
            app.rect = drawrectangle(app.ax2);
            
            % Update user instructions; make "Confirm"/"Cancel" buttons
            % available. 
            instructions = "Adjust selected region, or press 'Update selection' to confirm current selection."+newline;
            set(app.userinstructionsLabel,'Text',instructions);
            set(app.UpdateselectionButton, 'Enable','on');
            set(app.CancelselectionButton, 'Enable','on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'building name')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.userinstructionsLabel = uilabel(app.UIFigure);
            app.userinstructionsLabel.Text = 'user instructions';
            app.FinalizeButton = uibutton(app.UIFigure, 'push');
            app.FinalizeButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizeButtonPushed, true);
            app.FinalizeButton.Enable = 'off';
            app.FinalizeButton.Tooltip = {'Output current "coloured" region as a mask.'};
            app.FinalizeButton.Text = 'Finalize';
            app.UpdateselectionButton = uibutton(app.UIFigure, 'push');
            app.UpdateselectionButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateselectionButtonPushed, true);
            app.UpdateselectionButton.Enable = 'off';
            app.UpdateselectionButton.Text = {'Update'; 'selection'; ''};
            app.CancelselectionButton = uibutton(app.UIFigure, 'push');
            app.CancelselectionButton.ButtonPushedFcn = createCallbackFcn(app, @CancelselectionButtonPushed, true);
            app.CancelselectionButton.Enable = 'off';
            app.CancelselectionButton.Text = {'Cancel'; 'selection'};
            app.SelectionModeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectionModeButtonGroup.Title = 'Selection Mode';
            app.NewRegionButton = uitogglebutton(app.SelectionModeButtonGroup);
            app.NewRegionButton.Enable = 'off';
            app.NewRegionButton.Tooltip = {'Create new selection. Removes current selection.'};
            app.NewRegionButton.Text = 'New Region';
            app.NewRegionButton.Value = true;
            app.AddSelectionButton = uitogglebutton(app.SelectionModeButtonGroup);
            app.AddSelectionButton.Enable = 'off';
            app.AddSelectionButton.Tooltip = {'Add to current selection.'};
            app.AddSelectionButton.Text = 'Add Selection';
            app.RemoveSelectionButton = uitogglebutton(app.SelectionModeButtonGroup);
            app.RemoveSelectionButton.Enable = 'off';
            app.RemoveSelectionButton.Tooltip = {'Remove from current selection.'};
            app.RemoveSelectionButton.Text = 'Remove Selection';
            app.DrawNewButton = uibutton(app.UIFigure, 'push');
            app.DrawNewButton.ButtonPushedFcn = createCallbackFcn(app, @DrawNewButtonPushed, true);
            app.DrawNewButton.Enable = 'off';
            app.DrawNewButton.Tooltip = {'Output current "coloured" region as a mask.'};
            app.DrawNewButton.Text = 'Draw New';
            app.Panel = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roi_app_test(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end